classdef Qsliders < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        SpinnerQ2      matlab.ui.control.Spinner
        SpinnerQ5      matlab.ui.control.Spinner
        Q5Slider       matlab.ui.control.Slider
        Q5SliderLabel  matlab.ui.control.Label
        SpinnerQ4      matlab.ui.control.Spinner
        Q4Slider       matlab.ui.control.Slider
        Q4SliderLabel  matlab.ui.control.Label
        SpinnerQ3      matlab.ui.control.Spinner
        Q3Slider       matlab.ui.control.Slider
        Q3SliderLabel  matlab.ui.control.Label
        Q2Slider       matlab.ui.control.Slider
        Q2SliderLabel  matlab.ui.control.Label
        SpinnerQ1      matlab.ui.control.Spinner
        Q1Slider       matlab.ui.control.Slider
        Q1SliderLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        Q1 = 0;
        Q2 = 0;
        Q3 = 0;
        Q4 = 0;
        Q5 = 0;
        test
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Q1Slider
        function Q1SliderValueChanged(comp, event)
            
            comp.SpinnerQ1.Value = comp.Q1Slider.Value;
            comp.Q1 = comp.Q1Slider.Value;
        end

        % Value changed function: SpinnerQ1
        function SpinnerQ1ValueChanged(comp, event)
            comp.Q1Slider.Value = comp.SpinnerQ1.Value;
            comp.Q1 = comp.SpinnerQ1.Value; 
            
            
        end

        % Value changing function: SpinnerQ1
        function SpinnerQ1ValueChanging(comp, event)
            comp.Q1Slider.Value = event.Value;
            comp.Q1 = event.Value;
           

        end

        % Value changing function: Q1Slider
        function Q1SliderValueChanging(comp, event)
            comp.SpinnerQ1.Value = event.Value;
            comp.Q1 = event.Value;
           
        end

        % Value changed function: Q2Slider
        function Q2SliderValueChanged(comp, event)
            comp.SpinnerQ2.Value = comp.Q2Slider.Value;
            comp.Q2 = comp.Q2Slider.Value;
            
            
        end

        % Value changing function: Q2Slider
        function Q2SliderValueChanging(comp, event)
            comp.SpinnerQ2.Value = event.Value;
            comp.Q2 = event.Value;


        end

        % Value changed function: SpinnerQ2
        function SpinnerQ2ValueChanged(comp, event)
            comp.Q2Slider.Value = comp.SpinnerQ2.Value;
            comp.Q2 = comp.SpinnerQ2.Value; 
            
        end

        % Value changing function: SpinnerQ2
        function SpinnerQ2ValueChanging(comp, event)
            comp.Q2Slider.Value = event.Value;
            comp.Q2 = event.Value;
            
        end

        % Value changed function: Q3Slider
        function Q3SliderValueChanged(comp, event)
            comp.SpinnerQ3.Value = comp.Q3Slider.Value;
            comp.Q3 = comp.Q3Slider.Value;
            
        end

        % Value changing function: Q3Slider
        function Q3SliderValueChanging(comp, event)
            comp.SpinnerQ3.Value = event.Value;
            comp.Q3 = event.Value;
            
        end

        % Value changed function: SpinnerQ3
        function SpinnerQ3ValueChanged(comp, event)
            comp.Q3Slider.Value = comp.SpinnerQ3.Value;
            comp.Q3 = comp.SpinnerQ3.Value; 
            
        end

        % Value changing function: SpinnerQ3
        function SpinnerQ3ValueChanging(comp, event)
            comp.Q3Slider.Value = event.Value;
            comp.Q3 = event.Value;
            
            
        end

        % Value changed function: Q4Slider
        function Q4SliderValueChanged(comp, event)
            comp.SpinnerQ4.Value = comp.Q4Slider.Value;
            comp.Q4 = comp.Q4Slider.Value;
            
        end

        % Value changing function: Q4Slider
        function Q4SliderValueChanging(comp, event)
            comp.SpinnerQ4.Value = event.Value;
            comp.Q4 = event.Value;
            
        end

        % Value changed function: SpinnerQ4
        function SpinnerQ4ValueChanged(comp, event)
            comp.Q4Slider.Value = comp.SpinnerQ4.Value;
            
            comp.Q4 = comp.SpinnerQ4.Value; 
            
        end

        % Value changing function: SpinnerQ4
        function SpinnerQ4ValueChanging(comp, event)
            comp.Q4Slider.Value = event.Value;
            comp.Q4 = event.Value;
            
        end

        % Value changed function: Q5Slider
        function Q5SliderValueChanged(comp, event)
            comp.SpinnerQ5.Value = comp.Q5Slider.Value;
            comp.Q5 = comp.Q5Slider.Value;
            
        end

        % Value changing function: Q5Slider
        function Q5SliderValueChanging(comp, event)
            comp.SpinnerQ5.Value = event.Value;
            comp.Q5 = event.Value;

            
        end

        % Value changed function: SpinnerQ5
        function SpinnerQ5ValueChanged(comp, event)
            comp.Q5Slider.Value = comp.SpinnerQ5.Value;
            comp.Q5 = comp.SpinnerQ5.Value;
            
        end

        % Value changing function: SpinnerQ5
        function SpinnerQ5ValueChanging(comp, event)
            comp.Q5Slider.Value = event.Value;
            comp.Q5 = event.Value;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 395 272];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.Q1SliderLabel = uilabel(comp);
            comp.Q1SliderLabel.Text = 'Q1';
            comp.Q1Slider = uislider(comp);
            comp.Q1Slider.Limits = [-100 100];
            comp.Q1Slider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Q1SliderValueChanged, true);
            comp.Q1Slider.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @Q1SliderValueChanging, true);
            comp.SpinnerQ1 = uispinner(comp);
            comp.SpinnerQ1.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @SpinnerQ1ValueChanging, true);
            comp.SpinnerQ1.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SpinnerQ1ValueChanged, true);
            comp.Q2SliderLabel = uilabel(comp);
            comp.Q2SliderLabel.Text = 'Q2';
            comp.Q2Slider = uislider(comp);
            comp.Q2Slider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Q2SliderValueChanged, true);
            comp.Q2Slider.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @Q2SliderValueChanging, true);
            comp.Q3SliderLabel = uilabel(comp);
            comp.Q3SliderLabel.Text = 'Q3';
            comp.Q3Slider = uislider(comp);
            comp.Q3Slider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Q3SliderValueChanged, true);
            comp.Q3Slider.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @Q3SliderValueChanging, true);
            comp.SpinnerQ3 = uispinner(comp);
            comp.SpinnerQ3.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @SpinnerQ3ValueChanging, true);
            comp.SpinnerQ3.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SpinnerQ3ValueChanged, true);
            comp.Q4SliderLabel = uilabel(comp);
            comp.Q4SliderLabel.Text = 'Q4';
            comp.Q4Slider = uislider(comp);
            comp.Q4Slider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Q4SliderValueChanged, true);
            comp.Q4Slider.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @Q4SliderValueChanging, true);
            comp.SpinnerQ4 = uispinner(comp);
            comp.SpinnerQ4.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @SpinnerQ4ValueChanging, true);
            comp.SpinnerQ4.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SpinnerQ4ValueChanged, true);
            comp.Q5SliderLabel = uilabel(comp);
            comp.Q5SliderLabel.Text = 'Q5';
            comp.Q5Slider = uislider(comp);
            comp.Q5Slider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Q5SliderValueChanged, true);
            comp.Q5Slider.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @Q5SliderValueChanging, true);
            comp.SpinnerQ5 = uispinner(comp);
            comp.SpinnerQ5.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @SpinnerQ5ValueChanging, true);
            comp.SpinnerQ5.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SpinnerQ5ValueChanged, true);
            comp.SpinnerQ2 = uispinner(comp);
            comp.SpinnerQ2.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @SpinnerQ2ValueChanging, true);
            comp.SpinnerQ2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SpinnerQ2ValueChanged, true);
        end
    end
end