classdef Assingment2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TeachDobotXYZButton    matlab.ui.control.Button
        TeachMitsuXYZButton    matlab.ui.control.Button
        TeachDobotRobotButton  matlab.ui.control.Button
        RunSimulationButton    matlab.ui.control.Button
        TeachMitsuRobotButton  matlab.ui.control.Button
        ExitGUIButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExitGUIButton
        function ExitGUIButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: TeachMitsuRobotButton
        function TeachMitsuRobotButtonPushed(app, event)

            run ("Teach_Mitsu.mlapp")
            delete(app);

        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            run ("RunSimulation.mlapp")
            delete(app);
        end

        % Button pushed function: TeachDobotRobotButton
        function TeachDobotRobotButtonPushed(app, event)
            run ("Teach_Dobot.mlapp")
            delete(app);
        end

        % Button pushed function: TeachMitsuXYZButton
        function TeachMitsuXYZButtonPushed(app, event)
            run ("Teach_Mitsu_xyz.mlapp")
            delete(app);
        end

        % Button pushed function: TeachDobotXYZButton
        function TeachDobotXYZButtonPushed(app, event)
            run ("Teach_Dobot_xyz.mlapp")
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExitGUIButton = uibutton(app.UIFigure, 'push');
            app.ExitGUIButton.ButtonPushedFcn = createCallbackFcn(app, @ExitGUIButtonPushed, true);
            app.ExitGUIButton.Text = 'Exit GUI';
            app.TeachMitsuRobotButton = uibutton(app.UIFigure, 'push');
            app.TeachMitsuRobotButton.ButtonPushedFcn = createCallbackFcn(app, @TeachMitsuRobotButtonPushed, true);
            app.TeachMitsuRobotButton.Text = 'Teach Mitsu Robot';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.TeachDobotRobotButton = uibutton(app.UIFigure, 'push');
            app.TeachDobotRobotButton.ButtonPushedFcn = createCallbackFcn(app, @TeachDobotRobotButtonPushed, true);
            app.TeachDobotRobotButton.Text = 'Teach Dobot Robot';
            app.TeachMitsuXYZButton = uibutton(app.UIFigure, 'push');
            app.TeachMitsuXYZButton.ButtonPushedFcn = createCallbackFcn(app, @TeachMitsuXYZButtonPushed, true);
            app.TeachMitsuXYZButton.Text = 'Teach Mitsu XYZ';
            app.TeachDobotXYZButton = uibutton(app.UIFigure, 'push');
            app.TeachDobotXYZButton.ButtonPushedFcn = createCallbackFcn(app, @TeachDobotXYZButtonPushed, true);
            app.TeachDobotXYZButton.Text = 'Teach Dobot XYZ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Assingment2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end