classdef RunSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        RunSimulationButton_2     matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        Lamp_3                    matlab.ui.control.Lamp
        EStopSwitch               matlab.ui.control.ToggleSwitch
        EStopSwitchLabel          matlab.ui.control.Label
        Lamp_2                    matlab.ui.control.Lamp
        ContinueSimulationButton  matlab.ui.control.Button
        MainMenuButton            matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        %class = combinedClass1;
        Es = 0;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            run ("Assingment2.mlapp")
            delete(app);
        end

        % Value changed function: EStopSwitch
        function EStopSwitchValueChanged(app, event)
            value = app.EStopSwitch.Value;

        if strcmp(value, 'On')

            app.Es = 1


            app.Lamp_2.Visible = 'on';
            app.Lamp_3.Visible = 'off';
%         elseif strcmp(value, 'Off')
%             
%             app.Lamp_2.Visible = 'off';
        end
% %             
            
        end

        % Button pushed function: ContinueSimulationButton
        function ContinueSimulationButtonPushed(app, event)
            
            value = app.EStopSwitch.Value;

            if strcmp(value,'Off')
                 app.Es = 0
                app.Lamp_3.Visible = 'on';
                
            elseif strcmp(value,'On')
                app.Lamp_3.Visible = 'off';
                app.Lamp_2.Visible = 'on';
                 app.Es = 1
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Lamp_2.Visible = 'off';
            app.Lamp_3.Visible = 'off';
            app.EStopSwitch.Value = 'Off';
        end

        % Button pushed function: RunSimulationButton_2
        function RunSimulationButton_2Pushed(app, event)
             clear;
             % delete(app);
              Kill_Me_NOw();

              %testing();
            % delete(app);
             %delete(app);
             %combinedClass1.execution();
             
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {290, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MainMenuButton = uibutton(app.LeftPanel, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            app.ContinueSimulationButton = uibutton(app.LeftPanel, 'push');
            app.ContinueSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueSimulationButtonPushed, true);
            app.ContinueSimulationButton.Text = 'Continue Simulation';
            app.Lamp_2 = uilamp(app.LeftPanel);
            app.Lamp_2.Visible = 'off';
            app.EStopSwitchLabel = uilabel(app.LeftPanel);
            app.EStopSwitchLabel.Text = 'E-Stop';
            app.EStopSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.EStopSwitch.ValueChangedFcn = createCallbackFcn(app, @EStopSwitchValueChanged, true);
            app.EStopSwitch.Tag = 'E-stop';
            app.Lamp_3 = uilamp(app.LeftPanel);
            app.Lamp_3.Visible = 'off';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RunSimulationButton_2 = uibutton(app.LeftPanel, 'push');
            app.RunSimulationButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButton_2Pushed, true);
            app.RunSimulationButton_2.Text = 'Run Simulation';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunSimulation

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end