classdef Teach_Mitsu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        XSpinner           matlab.ui.control.Spinner
        SpinnerLabel       matlab.ui.control.Label
        StopButton         matlab.ui.control.Button
        StartButton        matlab.ui.control.Button
        YEditField_2       matlab.ui.control.NumericEditField
        YEditField_2Label  matlab.ui.control.Label
        PEditField         matlab.ui.control.NumericEditField
        PEditFieldLabel    matlab.ui.control.Label
        REditField         matlab.ui.control.NumericEditField
        REditFieldLabel    matlab.ui.control.Label
        ZEditField         matlab.ui.control.NumericEditField
        ZEditFieldLabel    matlab.ui.control.Label
        YEditField         matlab.ui.control.NumericEditField
        YEditFieldLabel    matlab.ui.control.Label
        XEditField         matlab.ui.control.NumericEditField
        XEditFieldLabel    matlab.ui.control.Label
        MainMenuButton     matlab.ui.control.Button
        Qsliders           Qsliders
        RightPanel         matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
properties (Access = public)

        x = 0;
        
        modelUR3 % Where I will put the loaded Mitsu
%         qz = [0     0     0     0     0     0];
        qrUR3 = [0 0 0 0 0 0];
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            run ("Assingment2.mlapp")
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            % app.x = 1;


 app.modelUR3 = Mitsubishi;
% 
%         axes(app.UIAxes);
%         cla(app.UIAxes)
%         ax = gca;
% 
%  % Delete the green floor surface (need to count backwards
%             % because once it is deleted the array is compressed)
%             for i = size(ax.Children,1):-1:1
%                 if strcmp(ax.Children(i).Type,'surface')
%                     delete(ax.Children(i));
%                 end
%             end
% 
%             % Transfer all the children across to the uiaxes parent EXCEPT
%             % the green floors
%             while 0 < length(ax.Children)                
%                 set(ax.Children(1),'Parent',app.UIAxes);
%             end
%             delete(get(gca,'parent'))
% 
%             % Scale the axes sensibly
%             app.UIAxes.DataAspectRatio = [1,1,1];
%             app.UIAxes.View = [45,45];


while(1)
    
            app.qrUR3(1) = app.Qsliders.Q1;
            app.qrUR3(2) = app.Qsliders.Q2;
            app.qrUR3(3) = app.Qsliders.Q3;
            app.qrUR3(4) = app.Qsliders.Q4;
            app.qrUR3(5) = app.Qsliders.Q5;
            app.qrUR3(6) = app.Qsliders.Q6;


            app.modelUR3.model.animate(app.qrUR3);
            drawnow();

            kine1 = app.modelUR3.model.fkine(app.qrUR3);
            app.XEditField.Value = kine1.t(1,1);
            app.YEditField.Value = kine1.t(2,1);
            app.ZEditField.Value = kine1.t(3,1);

            R = [kine1.n, kine1.o, kine1.a];
            % roll = 0;
            % pitch = 0;
            % yaw = 0;
            p = rotm2eul(R);
            roll = p(1,1);
            pitch = p(1,2);
            yaw = p(1,3);


            app.REditField.Value = roll;
            app.PEditField.Value = pitch;
            app.YEditField_2.Value = yaw;


end


%------------------------------------------


%             axes(app.UIAxes);
%             cla(app.UIAxes)
% 
% 
%             % Make, save and plot some robots            
% %             figure('visible','off'); % Makes the figure invisible when loading
%             ax = gca;
% 
%             app.modelUR3 = UR3;
% %             app.modelUR3.model.base = transl([-0.5,0,0]);
% 
% 
%             app.modelUR3.model.animate(app.qrUR3);
%             drawnow();
% 
%             % Delete the green floor surface (need to count backwards
%             % because once it is deleted the array is compressed)
%             for i = size(ax.Children,1):-1:1
%                 if strcmp(ax.Children(i).Type,'surface')
%                     delete(ax.Children(i));
%                 end
%             end
% 
%             % Transfer all the children across to the uiaxes parent EXCEPT
%             % the green floors
%             while 0 < length(ax.Children)                
%                 set(ax.Children(1),'Parent',app.UIAxes);
%             end
%             delete(get(gca,'parent'))
% 
%             % Scale the axes sensibly
%             app.UIAxes.DataAspectRatio = [1,1,1];
%             app.UIAxes.View = [45,45];
           % -------------------------
            % 
            % 
            % while(1)
            % 
            % app.qrUR3(1) = app.Qsliders.Q1;
            % app.qrUR3(2) = app.Qsliders.Q2;
            % app.qrUR3(3) = app.Qsliders.Q3;
            % app.qrUR3(4) = app.Qsliders.Q4;
            % app.qrUR3(5) = app.Qsliders.Q5;
            % 
            % 
            %  app.modelUR3.model.animate(app.qrUR3);
            % drawnow();
            % end


            % while(1)
            %   q = input('Enter joint values: ');
            %   app.qrUR3 = q;
            %   app.modelUR3.model.animate(app.qrUR3);
            %   drawnow();
            % end


            % while(1)
            %   % q = input('Enter joint values: ');
            %   app.qrUR3 = [app.Qsliders.Q1	    app.Qsliders.Q2   app.Qsliders.Q3    app.Qsliders.Q4	    app.Qsliders.Q5	    0];
            % %   app.qrUR3(1) = app.Qsliders.Q1;
            % % app.qrUR3(2) = app.Qsliders.Q2;
            % % app.qrUR3(3) = app.Qsliders.Q3;
            % % app.qrUR3(4) = app.Qsliders.Q4;
            % % app.qrUR3(5) = app.Qsliders.Q5;
            %   app.modelUR3.model.animate(app.qrUR3);
            %   drawnow();
            % end


            % t = timer('Period', 0.1, 'TimerFcn', @(~,~) app.modelUR3.update());
            % start(t);
            % while app.x == 
            % app.qrUR3(1) = app.Qsliders.Q1;
            % app.qrUR3(2) = app.Qsliders.Q2;
            % app.qrUR3(3) = app.Qsliders.Q3;
            % app.qrUR3(4) = app.Qsliders.Q4;
            % app.qrUR3(5) = app.Qsliders.Q5;
            % 
            % end


           % while app.x == 1 
           % 
           %  app.qrUR3(1) = app.Qsliders.Q1;
           %  app.qrUR3(2) = app.Qsliders.Q2;
           %  app.qrUR3(3) = app.Qsliders.Q3;
           %  app.qrUR3(4) = app.Qsliders.Q4;
           %  app.qrUR3(5) = app.Qsliders.Q5;
           % 
           % end


            % Turn off this load button and enable the move button
%             app.MakeLoadedPumaMoveButton.Enable = true;
%             app.MakeLoadedUR5MoveButton.Enable = true;
%             app.Load2RobotsButton.Enable = false;

% app.qrUR3{1,1} = app.Qsliders.comp.SpinnerQ1.Value;
% selectedButtonValue = get(selectedButton, 'Value');


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            clf;

        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
          
        end

        % Callback function: not associated with a component
        function XSpinnerValueChanged(app, event)
            
            
        end

        % Callback function: not associated with a component
        function XSpinnerValueChanging(app, event)
 
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {573, 573};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {412, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Qsliders = Qsliders(app.LeftPanel);
            app.MainMenuButton = uibutton(app.LeftPanel, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            app.XEditFieldLabel = uilabel(app.LeftPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.LeftPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.LeftPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.LeftPanel, 'numeric');
            app.REditFieldLabel = uilabel(app.LeftPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.LeftPanel, 'numeric');
            app.PEditFieldLabel = uilabel(app.LeftPanel);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.LeftPanel, 'numeric');
            app.YEditField_2Label = uilabel(app.LeftPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SpinnerLabel = uilabel(app.LeftPanel);
            app.SpinnerLabel.Text = 'Spinner';
            app.XSpinner = uispinner(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Teach_Mitsu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end