classdef Teach_Mitsu_xyz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        StopButton      matlab.ui.control.Button
        Z_Spinner       matlab.ui.control.Spinner
        ZLabel          matlab.ui.control.Label
        Y_Spinner       matlab.ui.control.Spinner
        YLabel          matlab.ui.control.Label
        X_Spinner       matlab.ui.control.Spinner
        XLabel          matlab.ui.control.Label
        StartButton     matlab.ui.control.Button
        MainMenuButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        x = 0;
        y = 0;
        z = 0;
        
        modelUR3 % Where I will put the loaded Mitsu
%         qz = [0     0     0     0     0     0];
        qrUR3 = [0 0 0 0 0 0];
         % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
             app.modelUR3 = Mitsubishi;


while(1)
    
            % app.qrUR3(1) = app.Qsliders.Q1;
            % app.qrUR3(2) = app.Qsliders.Q2;
            % app.qrUR3(3) = app.Qsliders.Q3;
            % app.qrUR3(4) = app.Qsliders.Q4;
            % app.qrUR3(5) = app.Qsliders.Q5;
            % app.qrUR3(6) = app.Qsliders.Q6;

           F = [1 0 0 app.x; 
               0 1 0 app.y; 
               0 0 1 app.z; 
               0 0 0 1];

           p = app.modelUR3.model.ikcon(F);

           app.qrUR3(1) = p(1);
            app.qrUR3(2) = p(2);
            app.qrUR3(3) = p(3);
            app.qrUR3(4) = p(4);
            app.qrUR3(5) = p(5);
            app.qrUR3(6) = p(6);


            app.modelUR3.model.animate(app.qrUR3);
            drawnow();

            % kine1 = app.modelUR3.model.fkine(app.qrUR3);
            % app.XEditField.Value = kine1.t(1,1);
            % app.YEditField.Value = kine1.t(2,1);
            % app.ZEditField.Value = kine1.t(3,1);
            % 
            % R = [kine1.n, kine1.o, kine1.a];
            % % roll = 0;
            % % pitch = 0;
            % % yaw = 0;
            % p = rotm2eul(R);
            % roll = p(1,1);
            % pitch = p(1,2);
            % yaw = p(1,3);
            % 
            % 
            % app.REditField.Value = roll;
            % app.PEditField.Value = pitch;
            % app.YEditField_2.Value = yaw;


end
        end

        % Value changed function: X_Spinner
        function X_SpinnerValueChanged(app, event)
            app.x = app.X_Spinner.Value;
            
        end

        % Value changing function: X_Spinner
        function X_SpinnerValueChanging(app, event)
            app.x = event.Value;
            
        end

        % Value changed function: Y_Spinner
        function Y_SpinnerValueChanged(app, event)
            app.y = app.Y_Spinner.Value;
            
        end

        % Value changing function: Y_Spinner
        function Y_SpinnerValueChanging(app, event)
            app.y = event.Value;
            
        end

        % Value changed function: Z_Spinner
        function Z_SpinnerValueChanged(app, event)
            app.z = app.Z_Spinner.Value;
            
        end

        % Value changing function: Z_Spinner
        function Z_SpinnerValueChanging(app, event)
            app.z = event.Value;
            
        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            run ("Assingment2.mlapp")
            delete(app);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clf;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainMenuButton = uibutton(app.UIFigure, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.X_Spinner = uispinner(app.UIFigure);
            app.X_Spinner.Step = 0.05;
            app.X_Spinner.ValueChangingFcn = createCallbackFcn(app, @X_SpinnerValueChanging, true);
            app.X_Spinner.ValueChangedFcn = createCallbackFcn(app, @X_SpinnerValueChanged, true);
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.Y_Spinner = uispinner(app.UIFigure);
            app.Y_Spinner.Step = 0.05;
            app.Y_Spinner.ValueChangingFcn = createCallbackFcn(app, @Y_SpinnerValueChanging, true);
            app.Y_Spinner.ValueChangedFcn = createCallbackFcn(app, @Y_SpinnerValueChanged, true);
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z';
            app.Z_Spinner = uispinner(app.UIFigure);
            app.Z_Spinner.Step = 0.05;
            app.Z_Spinner.ValueChangingFcn = createCallbackFcn(app, @Z_SpinnerValueChanging, true);
            app.Z_Spinner.ValueChangedFcn = createCallbackFcn(app, @Z_SpinnerValueChanged, true);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Teach_Mitsu_xyz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end